classdef Layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ColumnHeightEditField       matlab.ui.control.NumericEditField
        ColumnHeightEditFieldLabel  matlab.ui.control.Label
        BeamLengthEditField         matlab.ui.control.NumericEditField
        BeamLengthEditFieldLabel    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Node_coordinates % Description
    end
   
    
    methods (Access = private)
        
        function results = func(app)
            cla(app.UIAxes);
            L=app.BeamLengthEditField.Value;
            H=app.ColumnHeightEditField.Value;
            a=sqrt(3)/2;
            results=[-L 0.0 H;
                     -0.5*L a*L H;
                     0.5*L a*L H;
                     L 0.0 H;
                     0.5*L -a*L H;
                     -0.5*L -a*L H;
                     -L 0.0 0.0;
                     -0.5*L a*L 0.0;
                     0.5*L a*L 0.0;
                     L 0.0 0.0;
                     0.5*L -a*L 0.0;
                     -0.5*L -a*L 0.0;
                     ];
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,results(1:6,1),results(1:6,2),results(1:6,3),Color='k',LineWidth=1);
            plot3(app.UIAxes,results([1,6],1),results([1,6],2),results([1,6],3),Color='k',LineWidth=1);
            for i=1:6
            plot3(app.UIAxes,results([i,i+6],1),results([i,i+6],2),results([i,i+6],3),Color='k',LineWidth=1);
            
            end
            hold(app.UIAxes,"off");
            app.Node_coordinates=results;
        end

    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.func();
        end

        % Value changed function: BeamLengthEditField
        function BeamLengthEditFieldValueChanged(app, event)
            value = app.BeamLengthEditField.Value;
            app.func();
        end

        % Value changed function: ColumnHeightEditField
        function ColumnHeightEditFieldValueChanged(app, event)
            value = app.ColumnHeightEditField.Value;
            app.func();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin("base","Node_Coordinates",app.Node_coordinates);
            assignin("base","H",app.ColumnHeightEditField.Value);
            assignin("base","L",app.BeamLengthEditField.Value);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hexagonal Frame Layout')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BeamLengthEditFieldLabel = uilabel(app.UIFigure);
            app.BeamLengthEditFieldLabel.Text = 'Beam Length';
            app.BeamLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamLengthEditField.ValueChangedFcn = createCallbackFcn(app, @BeamLengthEditFieldValueChanged, true);
            app.BeamLengthEditField.Value = 1;
            app.ColumnHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnHeightEditFieldLabel.Text = 'Column Height';
            app.ColumnHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnHeightEditFieldValueChanged, true);
            app.ColumnHeightEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Layout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end