classdef beamprop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        BEAMSPanel           matlab.ui.container.Panel
        SUBMITButton         matlab.ui.control.Button
        Ixxm4EditField       matlab.ui.control.NumericEditField
        Ixxm4EditFieldLabel  matlab.ui.control.Label
        Izzm4EditField       matlab.ui.control.NumericEditField
        Izzm4EditFieldLabel  matlab.ui.control.Label
        Iyym4EditField       matlab.ui.control.NumericEditField
        Iyym4EditFieldLabel  matlab.ui.control.Label
        ShearModulusofelasticityNm2EditField  matlab.ui.control.NumericEditField
        ShearModulusofelasticityNm2EditFieldLabel  matlab.ui.control.Label
        YoungsModulusofelasticityNm2EditField  matlab.ui.control.NumericEditField
        YoungsModulusofelasticityNm2EditFieldLabel  matlab.ui.control.Label
        Aream2EditField      matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            assignin('base','Area',app.Aream2EditField.Value);
            assignin('base','E',app.YoungsModulusofelasticityNm2EditField.Value);
            assignin('base','G',app.ShearModulusofelasticityNm2EditField.Value);
            assignin('base','Iyy',app.Iyym4EditField.Value);
            assignin('base','Izz',app.Izzm4EditField.Value);
            assignin('base','Ixx',app.Ixxm4EditField.Value);
            Area = app.Aream2EditField.Value;
            E = app.YoungsModulusofelasticityNm2EditField.Value;
            G = app.ShearModulusofelasticityNm2EditField.Value;
            Iyy = app.Iyym4EditField.Value;
            Izz = app.Izzm4EditField.Value;
            Ixx = app.Ixxm4EditField.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin('base','Area',app.Aream2EditField.Value);
            assignin('base','E',app.YoungsModulusofelasticityNm2EditField.Value);
            assignin('base','G',app.ShearModulusofelasticityNm2EditField.Value);
            assignin('base','Iyy',app.Iyym4EditField.Value);
            assignin('base','Izz',app.Izzm4EditField.Value);
            assignin('base','Ixx',app.Ixxm4EditField.Value);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BEAMSPanel = uipanel(app.UIFigure);
            app.BEAMSPanel.Title = 'BEAMS';
            app.Label = uilabel(app.BEAMSPanel);
            app.Label.Text = 'Area(m^2)';
            app.Aream2EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.Aream2EditField.Value = 0.00966;
            app.YoungsModulusofelasticityNm2EditFieldLabel = uilabel(app.BEAMSPanel);
            app.YoungsModulusofelasticityNm2EditFieldLabel.Text = 'Youngs Modulus of elasticity(N/m^2)';
            app.YoungsModulusofelasticityNm2EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.YoungsModulusofelasticityNm2EditField.Value = 200000000000;
            app.ShearModulusofelasticityNm2EditFieldLabel = uilabel(app.BEAMSPanel);
            app.ShearModulusofelasticityNm2EditFieldLabel.Text = 'Shear Modulus of elasticity(N/m^2)';
            app.ShearModulusofelasticityNm2EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.ShearModulusofelasticityNm2EditField.Value = 76900000000;
            app.Iyym4EditFieldLabel = uilabel(app.BEAMSPanel);
            app.Iyym4EditFieldLabel.Text = 'Iyy(m^4)';
            app.Iyym4EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.Iyym4EditField.Value = 2.7283e-05;
            app.Izzm4EditFieldLabel = uilabel(app.BEAMSPanel);
            app.Izzm4EditFieldLabel.Text = 'Izz(m^4)';
            app.Izzm4EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.Izzm4EditField.Value = 2.0835e-05;
            app.Ixxm4EditFieldLabel = uilabel(app.BEAMSPanel);
            app.Ixxm4EditFieldLabel.Text = 'Ixx(m^4)';
            app.Ixxm4EditField = uieditfield(app.BEAMSPanel, 'numeric');
            app.Ixxm4EditField.Value = 4.8118e-05;
            app.SUBMITButton = uibutton(app.BEAMSPanel, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Local-axis-of-a-beam-x-is-the-longitudinal-direction-y-is-perpendicular-to-x-and-z-is.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beamprop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end